classdef ProwessGUI_02152024_commented < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ExperimentControlTab           matlab.ui.container.Tab
        StopCyclingButton              matlab.ui.control.Button
        ResumeButton                   matlab.ui.control.Button
        UpdateValuesButton             matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        StartCyclingButton             matlab.ui.control.Button
        SpecimenEditField              matlab.ui.control.EditField
        SpecimenEditFieldLabel         matlab.ui.control.Label
        PrimeStepsEditField            matlab.ui.control.NumericEditField
        PrimeStepsEditFieldLabel       matlab.ui.control.Label
        PrimeStartVEditField           matlab.ui.control.NumericEditField
        PrimeStartVEditFieldLabel      matlab.ui.control.Label
        IncludePrimingCheckBox         matlab.ui.control.CheckBox
        AmorPauseEditField             matlab.ui.control.NumericEditField
        AmorPauseEditFieldLabel        matlab.ui.control.Label
        AmorWidthusEditField           matlab.ui.control.NumericEditField
        AmorWidthusEditFieldLabel      matlab.ui.control.Label
        AmorVoltageVEditField          matlab.ui.control.NumericEditField
        AmorVoltageVEditFieldLabel     matlab.ui.control.Label
        CrysPauseEditField             matlab.ui.control.NumericEditField
        CrysPauseEditFieldLabel        matlab.ui.control.Label
        CrysWidthsEditField            matlab.ui.control.NumericEditField
        CrysWidthsEditFieldLabel       matlab.ui.control.Label
        CrysVoltageVEditField          matlab.ui.control.NumericEditField
        CrysVoltageVEditFieldLabel     matlab.ui.control.Label
        InitialInputsLabel             matlab.ui.control.Label
        SetupTab                       matlab.ui.container.Tab
        DatamodeSwitch                 matlab.ui.control.ToggleSwitch
        DatamodeSwitchLabel            matlab.ui.control.Label
        DefaultLabel                   matlab.ui.control.Label
        SavelocationDefaultisapplocationLabel  matlab.ui.control.Label
        DisplaySampleImageButton       matlab.ui.control.Button
        ImageFileNameEditField         matlab.ui.control.EditField
        ImageFileNameEditFieldLabel    matlab.ui.control.Label
        SpecifyFLIRDirectoryButton     matlab.ui.control.Button
        YEditField_2                   matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        XEditField_2Label              matlab.ui.control.Label
        BottomRightCornerCoordinatesLabel  matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        TopLeftCornerCoordinatesLabel  matlab.ui.control.Label
        CameraInformationLabel         matlab.ui.control.Label
        InfoTab                        matlab.ui.container.Tab
        BelowareexamplefieldnameinputsforreferenceLabel  matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        InstrumentsTab                 matlab.ui.container.Tab
        UpdatePlotCheckBox             matlab.ui.control.CheckBox
        ReadOscopeCheckBox             matlab.ui.control.CheckBox
        StorePulseDataCheckBox         matlab.ui.control.CheckBox
        TrPlot                         matlab.ui.control.UIAxes
        ScopePlot                      matlab.ui.control.UIAxes
        AutonomousControlTab           matlab.ui.container.Tab
        DamageVoltageReductionEditField  matlab.ui.control.NumericEditField
        DamageVoltageReductionEditFieldLabel  matlab.ui.control.Label
        DetectDamageCheckBox           matlab.ui.control.CheckBox
        DeltaGoalEditField             matlab.ui.control.NumericEditField
        DeltaGoalEditFieldLabel        matlab.ui.control.Label
        PadImgDelta                    matlab.ui.control.UIAxes
        FeedbackPlot                   matlab.ui.control.UIAxes
        CameraSetupTab                 matlab.ui.container.Tab
        PadImg                         matlab.ui.control.UIAxes
        CamImg                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        PadLabel                       matlab.ui.control.Label
        RndLabel                       matlab.ui.control.Label
        BatLabel                       matlab.ui.control.Label
        PauseLabel                     matlab.ui.control.Label
        CyclesremainingLabel           matlab.ui.control.Label
        StartCountdownButton           matlab.ui.control.Button
        CountdownEditField             matlab.ui.control.NumericEditField
        CountdownEditFieldLabel        matlab.ui.control.Label
        CycVal                         matlab.ui.control.Label
        CyclesnumberLabel              matlab.ui.control.Label
        AWVal                          matlab.ui.control.Label
        AmorWidthusLabel               matlab.ui.control.Label
        AAVal                          matlab.ui.control.Label
        AmorAmplitudeEditFieldLabel_2  matlab.ui.control.Label
        CWVal                          matlab.ui.control.Label
        CrysWidthEditFieldLabel_2      matlab.ui.control.Label
        CAVal                          matlab.ui.control.Label
        CrysAmplitudeEditFieldLabel_2  matlab.ui.control.Label
        AutomaticallyUpdateCheckBox    matlab.ui.control.CheckBox
        CurrentInputsLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private) % Do not change any of these values, they are all overwritten
        contCycle = true; % logical to terminate cycling
        primStart = 0; % priming starting voltage [V]
        primSteps = 1; % number of priming steps
        ScX = []; % Scope x axis [s]
        ScY = []; % Scope y axis [V]
        TrX = 0; % Tr x axis [SE]
        TrY = []; % Tr y axis [%Tr]
        crysAmp = 0; % current Crys Pulse Amplitude [V]
        crysDur = 1; % current Crys Pulse Duration [s]
        amorAmp = 0; % current Amor Pulse Amplitude [V]
        amorDur = 1; % current Amor Pulse Duration [s]
        crysPause = 30; % Time to wait after crystallizing [s]
        amorPause = 15; % Time to wait after amorphizing [s]
        pauseCycle = false; % logical to pause operations when button is clicked
        countdown = []; % number of cycles to terminate experiment [SE]
        tstart = []; % Used for displaying pulse shape correctly [s]
        tfin = 0; % Used for displaying pulse shape correctly [s]
        recPulse = false; % logical for recording pulse shape data
        autoUp = false; % logical for automatic parameter updating
        saveDir = ''; % path to log save location
        camDir = ''; % path to image save location - must be same as path in ResearchIR
        msv = []; % mean switching value [counts]
        areas = []; % switched area [pixels]
        meanCounts = []; % image mean count [counts]
        dmgInds = 0; % debugging var
        camps = []; % crys pulse amplitude history [V]
        aamps = []; % amor pulse amplitude history [V]
        cycs = []; % cycle number history [SE]
        dmgAreas = []; % history of damaged area [pixels]
        maxed = false; % legacy (disregard)
        tarea = []; % history of all damaged areas [pixels]
        rmask = []; % running mask of damaged area history [logical]
        delGoal = 1000; % goal delta R [counts]
        IncldPriming = []; % logical to send priming pulses
        expMode = false; % logical to toggle between transmission/reflection
    end
    
    methods (Access = private)

        function [fPowerSupply, fagi_01, fagi_02, fscope, dmm] = initialize(app)
            % This function will be run every time the start experiment
            % button is clicked. It will open each instrument as a visa
            % object, and reset the graphs and countdown feature.

            instrreset
            % Calling the instruments. Visa information will need to be
            % updated for use with new setups.
            fPowerSupply = visa('NI', 'USB0::0x2A8D::0x3002::MY61002249::0::INSTR');
            set (fPowerSupply,'OutputBufferSize',100000);
            fopen(fPowerSupply);
            fagi_01 = visa('NI', 'USB0::0x0957::0x0407::MY44032307::0::INSTR'); % function generator for triggering PROWESS
            set (fagi_01,'OutputBufferSize',100000);
            fopen(fagi_01);
            fagi_02 = visa('NI', 'USB0::0x0957::0x0407::SG44000855::0::INSTR'); % function generator for triggering FLIR
            set (fagi_02,'OutputBufferSize',100000);
            fopen(fagi_02);
            fscope = visa('NI','USB0::0x0699::0x0456::C013976::INSTR');
            set (fscope,'InputBufferSize',100000);
            fopen(fscope);
            dmm = visa('KEYSIGHT', 'USB0::0x0957::0x0607::MY47003788::0::INSTR');
            set (dmm,'InputBufferSize',100000);
            fopen(dmm);

            % Power Supply Initialization
            fprintf(fPowerSupply,'CURRENT 0.5');
            
            % Function Generator 01 Initialization - PULSE
            fprintf(fagi_01,'FREQ 10e3'); % Set the frequency for amorphization
            fprintf(fagi_01,'FUNCTION PULSE'); % Put the tool in pulse mode
            fprintf(fagi_01,'FUNCtion:PULSe:WIDTh 10e-6'); % Set the pulse Width for amorphization
            fprintf(fagi_01,'BURST:STATE ON'); %  Make it burst so that we send only a burst of pulses at a time
            fprintf(fagi_01,'BURST:MODE TRIGGERED'); % Make it triggered mode
            fprintf(fagi_01,'BURST:NCYCLES 1'); % Send only 1 pulse
            fprintf(fagi_01,'TRIGGER:SOURCE BUS'); % Use the USB bus as trigger
            fprintf(fagi_01,'VOLTAGE:HIGH 5'); %
            fprintf(fagi_01,'VOLTAGE:LOW 0'); % Set it to 0 so that the transistor is turned off
            fprintf(fagi_01,'OUTPUT:LOAD 50'); % Set output load to 50 ohms - maybe not needed but seems to work
            fprintf(fagi_01,'WAVEFORM:SOURCE CH1'); % Configure scope for cont. data transmission
            fprintf(fagi_01,'WAVEFORM:FORMAT ASCII'); % Set waveform read to ascii

            % Function Generator 02 Initialization - FLIR
            fprintf(fagi_02,'FREQ 200e-3'); % Set the frequency for image capture
            fprintf(fagi_02,'FUNCTION PULSE'); % Put the tool in pulse mode
            fprintf(fagi_02,'FUNCtion:PULSe:WIDTh 1'); % Set the pulse Width for image capture
            fprintf(fagi_02,'BURST:STATE ON'); %  Make it burst so that we send only a burst of pulses at a time
            fprintf(fagi_02,'BURST:MODE TRIGGERED'); % Make it triggered mode
            fprintf(fagi_02,'BURST:NCYCLES 1'); % Send only 1 pulse
            fprintf(fagi_02,'TRIGGER:SOURCE BUS'); % Use the USB bus as trigger
            fprintf(fagi_02,'VOLTAGE:HIGH 5'); %
            fprintf(fagi_02,'VOLTAGE:LOW 0'); % Set it to 0 so that the transistor is turned off
            fprintf(fagi_02,'OUTPUT:LOAD 50'); % Set output load to 50 ohms - maybe not needed but seems to work
            fprintf(fagi_02,'OUTPUT ON'); % keep the output of FG 2 constantly ON
            
            % Oscilloscope Initialization

            hold(app.ScopePlot,'off')
            fprintf(fscope,'HORIZONTAL:DELAY:MODE OFF');
            fprintf(fscope,'DATA:START 151');
            fprintf(fscope,'DATA STOP 10000');
            fprintf(fscope,'DAT:SOURCE CH1');

            % Clear all axes and add color bars to the appropriate plots
            cla(app.FeedbackPlot)
            cla(app.ScopePlot)
            cla(app.TrPlot)
            cla(app.PadImgDelta)
            colorbar(app.PadImgDelta,'eastoutside')
            colorbar(app.PadImg,'eastoutside')
            colorbar(app.CamImg,'eastoutside')
            
            % Reset countdown
            app.countdown = [];
            app.CyclesremainingLabel.Text = 'Cycles remaining: inf';
            app.ScopePlot.XLimMode = 'auto';
            hold(app.TrPlot,'on');
            yyaxis(app.TrPlot,'right');
            ylabel(app.TrPlot,'Resistance [Ohms]');
            % legend(app.ScopePlot,{'Function Generator', 'Device'})

            disp('Initialization Completed...')
            pause(1)
            app.tstart = tic;
        end
                    
        function [] = cycle(app, fPowerSupply, fagi_01, fagi_02, fscope, dmm)
            % update GUI display
            app.contCycle = true;
            app.ScX = 0;
            app.ScY = 0;
            app.CAVal.Text = sprintf('%f.1',app.crysAmp);
            app.CWVal.Text = sprintf('%f.2',app.crysDur);
            app.AAVal.Text = sprintf('%f.1',app.amorAmp);
            app.AWVal.Text = sprintf('%f.1',app.amorDur * 1E6);
            
            % query date and time from the oscope for file naming
            expDate = query(fscope,'DATE?');
            expTime = query(fscope,'TIME?');
            % convert date and time to strings usable for file naming
            expDatef = [expDate(2:5), ' ', expDate(7:8), ' ', expDate(10:11)];
            expTimef = [expTime(2:3), ' ', expTime(5:6), ' ', expTime(8:9)];
          
            % create parameter info log file
            fdata = fopen(fullfile(app.saveDir,sprintf('Prowess Data %s %s %s.txt', app.SpecimenEditField.Value, expDatef, expTimef)), 'wt');
            fprintf(fdata,sprintf('%s - Experiment started on %s at %s\n',app.SpecimenEditField.Value, expDate(2:end-2),expTime(2:end-2)));
            fprintf(fdata,sprintf('Cycle\tPrimeDur\tPrimeInt\tPrimVolt\tPulseDur\tPulseVolt\tPause\tResistance\n'));
            name = lower(app.SpecimenEditField.Value);

            % create pulse shape log file
            fpulse = fopen(fullfile(app.saveDir,sprintf('Pulses %s %s %s.txt', app.SpecimenEditField.Value, expDatef, expTimef)), 'wt');
            fprintf(fpulse,'Time\tCH1\tCH2\n');
            fprintf(fpulse,sprintf('%s - Experiment started on %s at %s\n',app.SpecimenEditField.Value, expDate(2:end-2),expTime(2:end-2)));
            fields = strsplit(name,'_');

            % display the experiment information on the GUI and determine
            % the starting cycle number from the specimen name
            cycMask = contains(fields,'cyc');
            batMask = contains(fields,'bat');
            rndMask = contains(fields,'roun');
            padMask = contains(fields,'pad');
            if sum(batMask)==1
                numMask = fields{batMask}>47 & fields{batMask}<59;
                app.BatLabel.Text = sprintf('Batch %d', str2double(fields{batMask}(numMask)));
            end
            if sum(rndMask)==1
                numMask = fields{rndMask}>47 & fields{rndMask}<59;
                app.RndLabel.Text = sprintf('Round %d', str2double(fields{rndMask}(numMask)));
            end
            if sum(padMask)==1
                numMask = fields{padMask}>47 & fields{padMask}<59;
                app.PadLabel.Text = sprintf('Pad %d', str2double(fields{padMask}(numMask)));
            end
            if sum(cycMask)==1
                numMask = fields{cycMask}>47 & fields{cycMask}<59;
                cyc = str2double(fields{cycMask}(numMask));
            else
                cyc = 0;
            end
            count = cyc+1;
            
            app.CycVal.Text = sprintf('%d',count);
            sE = 0;

            % start sending pulses
            while app.contCycle
                tic
                % increment count and write to log
                app.cycs = [app.cycs count];
                fprintf(fdata,sprintf('%d\t0.2\t0.4\t',count));
                

                % Start recording
                fprintf(fagi_02,'TRIG:SOUR BUS;*TRG'); % sends a trigger to FLIR to initate image acquisition
                pause(0.5)

                app.ScX(end+1) = app.ScX(end) + .5; % obsolete
                app.ScY(end+1) = app.ScY(end); % obsolete
                
                    if app.IncldPriming ==1
                        % Priming
                        disp('Priming Begins')
                        primingVolt = linspace(app.primStart,app.crysAmp,app.primSteps); % create priming voltages based on start and crys values
                        primingVolt(end) = [];
                        
                        pulse(app, 5, .2, primingVolt, fPowerSupply, fagi_01, fscope, 1, fpulse); % send priming pulses
                        fprintf(fdata,sprintf('%.2f-%.2f\t',primingVolt(1),primingVolt(end))); % write to log
        
                        disp('Priming Completed')
                        % pause(1)
                        app.ScX(end+1) = app.ScX(end) + 1; % obsolete
                        app.ScY(end+1) = app.ScY(end); % obsolete
                    end

                disp('Crystallization Begins')

                % Crystallization process begins
                
                pulse(app, .5, app.crysDur, app.crysAmp, fPowerSupply, fagi_01, fscope, 2, fpulse); % send crys pulse
                fprintf(fdata,sprintf('%.2f\t%.2f\t%.2f\t',app.crysDur,app.crysAmp,app.crysPause)); % write to log

                disp('Crystallization Completed')
                pause(app.crysPause) % wait for user-specified time
                if ~isempty(app.ImageFileNameEditField.Value); getImg(app,count+1); end % display image if image path specified
                app.ScX(end+1) = app.ScX(end) + app.crysPause; % obsolete
                app.ScY(end+1) = app.ScY(end); % obsolete
                count = count+1; % increment count

                % measure and plot resistance
                yyaxis(app.TrPlot,'right');
                ohms = str2double(query(dmm,'MEAS:RES? 100,.01'));
                fprintf(fdata,sprintf('%.2f\n',ohms));
                if app.UpdatePlotCheckBox.Value
                    plot(app.TrPlot, count-1,ohms,'r*')
                end

                % decrement countdown if countdown in progress
                if ~isempty(app.countdown)
                    app.countdown = app.countdown - 1;
                    app.CyclesremainingLabel.Text = sprintf('Cycles remaining: %d',app.countdown);
                end
                app.CycVal.Text = sprintf('%d',count);
                toc

                % infinite loop while app is paused
                while app.pauseCycle
                    pause(1);
                end

                % start writing amorphization info to log
                fprintf(fdata,sprintf('%d\t',count));
                disp('Amorphization Begins')
                tic
                app.cycs = [app.cycs count];
                fprintf(fdata,sprintf('0\t0\t0\t'));
                % Start recording
                fprintf(fagi_02,'TRIG:SOUR BUS;*TRG'); % sends a trigger to FLIR to initate image acquisition
                pause(2)
                app.ScX(end+1) = app.ScX(end) + 2; % obsolete
                app.ScY(end+1) = app.ScY(end); % obsolete


                % Amorphization process begins
                
                pulse(app, 10E3, app.amorDur, app.amorAmp, fPowerSupply, fagi_01, fscope, 3, fpulse); % send amor pulse
                fprintf(fdata,sprintf('%.7f\t%.2f\t%.2f\t',app.amorDur,app.amorAmp,app.amorPause)); % write to log
                
                
                disp('Amorphization Completed')
                pause(app.amorPause) % wait for user-specifed time
                app.ScX(end+1) = app.ScX(end) + app.amorPause; % obsolete
                app.ScY(end+1) = app.ScY(end); % obsolete
                count = count+1; % increment count

                % measure and plot resistance
                yyaxis(app.TrPlot,'right');
                ohms = str2double(query(dmm,'MEAS:RES? 100,.01'));
                fprintf(fdata,sprintf('%.2f\n',ohms));
                if app.UpdatePlotCheckBox.Value
                    plot(app.TrPlot, count-1,ohms,'r*')
                end

                % decrement countdown if countdown in progress
                if ~isempty(app.countdown)
                    app.countdown = app.countdown - 1;
                    app.CyclesremainingLabel.Text = sprintf('Cycles remaining: %d',app.countdown);
                    if app.countdown<=0
                        app.contCycle = false;
                    end
                end
                app.CycVal.Text = sprintf('%d',count);
                toc
                % 
                % write current amplitudes to appropriate amplitude
                % histories. This is used for plotting
                app.camps = [app.camps app.crysAmp];
                app.aamps = [app.aamps app.amorAmp];

                % infinite loop while app is paused
                while app.pauseCycle
                    pause(1);
                end

                if ~isempty(app.ImageFileNameEditField.Value); readimg(app,count-1); end % display and process image if path specified

                % update onscreen text
                app.CAVal.Text = sprintf('%.1f',app.crysAmp);
                app.CWVal.Text = sprintf('%.2f',app.crysDur);
                app.AAVal.Text = sprintf('%.1f',app.amorAmp);
                app.AWVal.Text = sprintf('%.1f',app.amorDur * 1E6);

                % update plotting xlims with current time
                app.ScopePlot.XLim = [toc(app.tstart), toc(app.tstart)+app.crysPause+app.amorPause+5+app.primSteps];
                
            end
            

            % close all visa objects and log files once experiment is over
            fclose(fPowerSupply);
            fclose(fagi_01);
            fclose(fagi_02);
            fclose(fscope);
            fclose(dmm);
            fclose(fdata);
            fclose(fpulse);

            % save(sprintf('Prowess Data %s %s %s.mat', app.SpecimenEditField.Value, expDatef, expTimef));
            % clear;
        end

        function [] = pulse(app, freq, dur, volt, fPowerSupply, fagi_01, fscope, x, fpulse)
            % This function is used to send all the pulses to the device.
            % It can support sending a single pulse (when 'volt' is a
            % scalar) or multiple pulses (when 'volt' is a vector). The
            % latter is used to send a series of pulses for priming. This
            % function will also trigger the pulse shape acquisition from
            % the oscope after sending each pulse. The input variable 'x'
            % is used to pass in information regarding which pulse is being
            % sent. x=1 is priming, x=2 is crystallizing, and x=3 is
            % amorphizing. 'freq', 'dur', and 'volt' refer to the pulse
            % frequency, duration, and amplitude respectively.


            % setting up pulse parameters from function inputs
            fprintf(fagi_01,['FREQ ', num2str(freq)]);
            fprintf(fagi_01,sprintf('FUNCtion:PULSe:WIDTh %.10f', dur));
            fprintf(fagi_01,'BURST:NCYCLES 1'); % Send only 1 pulse
            fprintf(fagi_01,'OUTPUT ON'); %enable output f;unction generator
            fprintf(fPowerSupply,'OUTPUT ON'); %enable output power supply
            % fprintf(fscope,sprintf('WFMI:XINCR %.10f', dur/2500));
            app.ScX(end+1) = app.ScX(end) + .5;
            app.ScY(end+1) = 0;
            fprintf(fscope,'HORIZONTAL:SCALE %d',dur*1.25/5);

            pause(.5)

            % Set the desired voltage and write the operation to log
            for i = 1:length(volt)
                fprintf(fPowerSupply,['VOLtage ', num2str(volt(i))]);
                switch x
                    case 1 
                        pause(.4)
                        fprintf(fpulse,'Priming\n');
                        
                    case 2
                        pause(.2)
                        fprintf(fpulse,'Crystallizing\n');
                        
                    case 3
                        pause(.1)
                        fprintf(fpulse,'Amorphizing\n');
                        
                end
                % tread = toc(app.tstart);
                fprintf(fagi_01,'TRIG:SOUR BUS;*TRG'); % actual command to send the pulse
                st = toc(app.tstart); % used for plotting

                % empirically determined pauses needed for accurate
                % waveform capture from the oscope
                switch x
                    case 1
                        pause(.2)
                    case 2
                        pause(app.crysDur*1.25)
                    case 3
                        pause(0.001);
                end

                % read the pulse shape
                if app.ReadOscopeCheckBox.Value
                    readScope(app,fscope,st,dur,fpulse,x)
                end
               
            end
            
            fprintf(fPowerSupply,'VOLTAGE 1'); % set voltage to 1 for safety
            fprintf(fagi_01,'OUTPUT OFF'); % turn output off
            fprintf(fPowerSupply,'OUTPUT OFF'); % turn output off
        end

        function [] = readScope(app,fscope,st,dur,fpulse,y)
            % This function captures the waveform from the oscope and plots
            % it on the appropriate axes. It also writes the waveform data
            % to log if desired. The raw waveform data is converted to a
            % more intuitive format by reversing the polarity of one of the
            % channels as well as rescaling the data from the scaled down
            % oscope display

            % queries the data from each channel and stores it locally
            fprintf(fscope,'CURVE?');
            data = binblockread(fscope);
            fprintf(fscope,'DAT:SOURCE CH2');
            fprintf(fscope,'CURVE?');
            data2 = binblockread(fscope);
            
            % aesthetic - connects the current pulse to the previous one
            plot(app.ScopePlot,[app.tfin st],[0 0],'b-');
            plot(app.ScopePlot,[app.tfin st],[0 0],'r--');

            % post-processing to convert 'data' and 'data2' to the
            % waveforms. this is needed since the oscope sends scaled data
            % and it autoscales based on the amplitude of the pulse.
            mult = str2double(query(fscope,'WFMO:YMULT?'));
            mask = data>127;
            data2(data2>127) = data2(data2>127)-255;
            data(mask) = data(mask)-255;
            data = ((data*mult)*123);
            data2 = ((data2*mult)*246);
            data2 = data2 - mean(maxk(data2(2:2:end),1000));
            data(data<0) = 0;
            % int = str2double(query(fscope,'WFMO:XINCR?'));
            int = dur*2/9850;
            x = 1:length(data(2:2:end));
            
            app.TrX = st + x*int;

            % empirically determined x-shifts needed for each pulse type. y
            % in this context is the same as x in the parent pulsing function
            switch y
                case 1
                    redx = app.TrX+.067;
                case 2
                    redx = app.TrX+.067;
                    cmask = abs(data2(2:2:end)-mean(maxk(data2(2:2:end),500))) < 1;
                    cstart = find(cmask,100);
                    data2(2:2:cstart(end)) = 0;
                case 3
                    redx = app.TrX;
            end

            app.tfin = app.TrX(end);
            
            % plot the post-processed data
            plot(app.ScopePlot,app.TrX,data(2:2:end),'b-')
            hold(app.ScopePlot, 'on')
            plot(app.ScopePlot,redx,data2(2:2:end),'r-')
            fprintf(fscope,'DAT:SOURCE CH1');

            % write the pulse data to log if desired. This process adds
            % signinficant time due to the size of the data and this
            % feature should be turned off to maximize experiment
            % efficiency
            if app.recPulse
                for i = x*2
                    fprintf(fpulse,'%.9f, %.3f, %.3f\n',app.TrX(i/2),data(i),data2(i));
                end
            end
        end

        
        function [] = readimg(app, ind)
            % This function is used to update the images shown in the GUI
            % and also conduct all the image processing needed to
            % autonomously update the parameters. A more detailed
            % explanation of the method by which this is done can be found
            % in the GUI paper supplementary information. The comments in
            % this section will be focused mainly on the code itself.

            % acquire the first image. if the image was taken during a
            % camera buffer, decrement the SE of the image by 2 and try
            % again until a valid image is seen.
            bufCheck = false;
            i = ind;
            while ~bufCheck
                v = FlirMovieReader(fullfile(app.camDir,sprintf('%s-%06d.ats',app.ImageFileNameEditField.Value,i-1)));
                
                IR_Cam_data1 = step(v);
                if mean(mean(IR_Cam_data1)) < 4500 % camera buffer check
                    i = i-2;
                else 
                    bufCheck = true;
                end
            end

            % acquire the second image. if the image was taken during a
            % camera buffer, decrement the SE of the image by 2 and try
            % again until a valid image is seen.
            bufCheck = false;
            i = ind;
            while ~bufCheck
                v = FlirMovieReader(fullfile(app.camDir,sprintf('%s-%06d.ats',app.ImageFileNameEditField.Value,i)));
               
                IR_Cam_data2 = step(v); 
                if mean(mean(IR_Cam_data2)) < 4500 % camera buffer check
                    i = i-2;
                else 
                    bufCheck = true;
                end
            end
            

            % flip the order of the images if operating in transmission,
            % since the expected trends would be reversed.
            if app.expMode
                im2 = IR_Cam_data2;
                IR_Cam_data2 = IR_Cam_data1;
                IR_Cam_data1 = im2;
            end

            % subtract the two images to obtain the delta/contrast image
            IR_Cam_dataS = abs(double(IR_Cam_data1) - double(IR_Cam_data2));

            damaged = false; % initialization only

            % crop the delta image to focus on only the pad and get the
            % dimensions of the pad in pixels
            pad = IR_Cam_dataS(app.YEditField.Value:app.YEditField_2.Value,app.XEditField.Value:app.XEditField_2.Value);
            [a1, a2] = size(pad); % pad dims [pixels]

            % display the delta image on the GUI
            imagesc(pad,'Parent',app.PadImgDelta)

            % image processing steps:
            maxcounts = mean(double(maxk(pad(:),round(a1*a2*.1)))); % the average delta R of the 10% best performing pixels
            avgCounts = mean(mean(pad)); % mean delta R of the pad
            mask = pad > avgCounts * (a1*a2) / sum(sum(pad>(maxcounts*.5))) & pad > 50; % 'good switching' regions of the pad
            if isempty(app.rmask)
                app.rmask = mask; % initialize the running good switching mask
            else
                app.rmask = app.rmask+mask; % update the running good switching mask
            end
            dmgMask = pad<50; % damaged areas of the pad
            switchedArea = sum(sum(mask)); % pad area that switched this cycle
            
            % update tracking vars for switched area, damaged area, and
            % total switched area
            app.areas = [app.areas switchedArea];
            app.dmgAreas = [app.dmgAreas (sum(sum(dmgMask)))];
            app.tarea = [app.tarea sum(sum(app.rmask))];

            % find mean counts of each of the phases
            app.meanCounts(end+1) = mean(mean(IR_Cam_data1(app.YEditField.Value:app.YEditField_2.Value,app.XEditField.Value:app.XEditField_2.Value)));
            app.meanCounts(end+1) = mean(mean(IR_Cam_data2(app.YEditField.Value:app.YEditField_2.Value,app.XEditField.Value:app.XEditField_2.Value)));
    
            % find the mean switching value of the cycle
            meanSwitchVal = mean(app.meanCounts(end-1:end));
            app.msv = [app.msv meanSwitchVal];

            % update the displays for the 'Camera Setup' tab of the GUI
            imagesc(IR_Cam_data2,'Parent',app.CamImg)
            imagesc(IR_Cam_data2(app.YEditField.Value:app.YEditField_2.Value,app.XEditField.Value:app.XEditField_2.Value),'Parent',app.PadImg)
            
            % determining how to update the pulse parameters every 14 SEs
            % as long as the delta R goal is not met
            if mod(length(app.meanCounts),14)==0 && app.autoUp && (abs(app.meanCounts(end) - app.meanCounts(end-1)) < app.delGoal)

                if length(app.dmgAreas)>12 && app.DetectDamageCheckBox.Value
                    % linear regression on damaged areas
                    [c,S] = polyfit(1:13,app.dmgAreas(end-12:end),1);
                    % linear regression on switching areas
                    c2 = polyfit(1:13,app.areas(end-12:end),1);
                    % r^2 value of damaged area regression
                    r2 = 1 - (S.normr/norm(app.dmgAreas(end-12:end) - mean(app.dmgAreas(end-12:end))))^2;
                   
                    if (c(1) > 0 && r2>.4) || c2(1) < -10 % if damage is trending up, reduce pulse voltages
                        app.crysAmp = app.crysAmp - app.DamageVoltageReductionEditField.Value;
                        app.amorAmp = app.amorAmp - app.DamageVoltageReductionEditField.Value;
                        app.dmgInds = [app.dmgInds ind];
                        % disp('Damage!')
                        damaged(end+1) = true; % prevent any further pulse increases
                    else
                        damaged(end+1) = false; % allow further pulse increases
                    end
                end

                if app.tarea(end)/(a1*a2) > 1 % obsolete
                    app.maxed = true; % obsolete
                end % obsolete

                % linear regression for crsyalline mean counts
                a = polyfit(1:7,app.meanCounts(end-13:2:end-1),1);
                if (a(1) < -1 || mean(app.meanCounts(end-13:2:end-1))<mean(app.meanCounts(1:2))) && ~damaged(end)
                    % if crystalline is trending down or is lower than the
                    % initial values, increase crys voltage
                    app.crysAmp = app.crysAmp + .25;
                    if a(1) < -5 % if very steep decrease, lower amor voltage too
                        app.amorAmp = app.amorAmp - .25;
                    end
                end

                % linear regression for amorphous mean counts
                b = polyfit(1:7,app.meanCounts(end-12:2:end),1);
                if (b(1) > .5 || mean(app.meanCounts(end-12:2:end-1))>mean(app.meanCounts(1:2))) && ~damaged(end)
                    % if amorphous is trending up or is higer than the
                    % initial values, increase amor voltage
                    app.amorAmp = app.amorAmp + .25;
                    if b(1) > 5 % if very steep increase, lower crys voltage too
                        app.crysAmp = app.crysAmp - .25;
                    end
                end

                % linear regression on mean switching value to determine
                % stability. Flatness here means that the pulses are having
                % an equal effect on the phase transition in both
                % directions
                d = polyfit(1:7, app.msv(end-6:end),1);
                if abs(d(1)) < .15 && max(app.dmgInds) < ind-14 && ~damaged(end)
                    % if flat, increase both voltages to try to increase
                    % the delta R
                    app.crysAmp = app.crysAmp + .25;
                    app.amorAmp = app.amorAmp + .25;
                end

            end


            % updating the appropriate plots
            hold(app.FeedbackPlot,'off')
            yyaxis(app.FeedbackPlot,'left')
            plot(app.FeedbackPlot, app.cycs(1:2:end), app.camps(1:end), 'b-')
            hold(app.FeedbackPlot,'on')
            plot(app.FeedbackPlot, [app.cycs(end-1) app.cycs(end)+1], [app.camps(end) app.crysAmp],'b--')
            plot(app.FeedbackPlot, app.cycs(2:2:end), app.aamps(1:end), 'r-')
            plot(app.FeedbackPlot, [app.cycs(end) app.cycs(end)+2], [app.aamps(end) app.amorAmp],'r--')
            xlim(app.FeedbackPlot,'auto')
            ylim(app.FeedbackPlot,'auto')
            yyaxis(app.FeedbackPlot,'right')
            plot(app.FeedbackPlot,app.cycs,app.meanCounts,'k-')
            ylim(app.FeedbackPlot,'auto')
            if app.UpdatePlotCheckBox.Value
                yyaxis(app.TrPlot,'left')
                plot(app.TrPlot,app.cycs(end-1),app.meanCounts(end-1),'b*')
                plot(app.TrPlot,app.cycs(end),app.meanCounts(end),'b*')
                xlim(app.TrPlot,'auto')
                ylim(app.TrPlot,'auto')
            end
        end
        
        function [] = getImg(app,ind)
            % This function just gets the images for the crystallization
            % pulse and displays them in the plots on the 'Camera Setup'
            % tab in the GUI.
            if ind<11
                v = FlirMovieReader(fullfile(app.camDir,strcat(app.ImageFileNameEditField.Value,'-00000',num2str(ind-1),'.ats')));
            elseif ind<101
                v = FlirMovieReader(fullfile(app.camDir,strcat(app.ImageFileNameEditField.Value,'-0000',num2str(ind-1),'.ats')));
            elseif ind<1001
                v = FlirMovieReader(fullfile(app.camDir,strcat(app.ImageFileNameEditField.Value,'-000',num2str(ind-1),'.ats')));
            elseif ind<10001
                v = FlirMovieReader(fullfile(app.camDir,strcat(app.ImageFileNameEditField.Value,'-00',num2str(ind-1),'.ats')));
            else
                v = FlirMovieReader(fullfile(app.camDir,strcat(app.ImageFileNameEditField.Value,'-0',num2str(ind-1),'.ats')));
            end
            IR_Cam_data1 = step(v);
            pad = IR_Cam_data1(app.YEditField.Value:app.YEditField_2.Value,app.XEditField.Value:app.XEditField_2.Value);

            imagesc(IR_Cam_data1,'Parent',app.CamImg)
            imagesc(pad,'Parent',app.PadImg)
        
        end
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartCyclingButton
        function StartCyclingButtonPushed(app, event)
            % reset all internal variables
            app.msv = [];
            app.areas = [];
            app.meanCounts = [];
            app.dmgInds = 0;
            app.camps = [];
            app.aamps = [];
            app.cycs = [];
            app.dmgAreas = [];
            app.maxed = false;
            app.tarea = [];
            app.rmask = [];

            % get pulse parameters from the input fields
            app.crysAmp = app.CrysVoltageVEditField.Value;
            app.crysDur = app.CrysWidthsEditField.Value;
            app.amorAmp = app.AmorVoltageVEditField.Value;
            app.amorDur = app.AmorWidthusEditField.Value * 1E-6;
            app.primStart = app.PrimeStartVEditField.Value;
            app.primSteps = app.PrimeStepsEditField.Value;
            app.crysPause = app.CrysPauseEditField.Value;
            app.amorPause = app.AmorPauseEditField.Value;
            
            % initialize the instruments
            [fp, f1, f2, fs, dmm] = initialize(app);
            % start cycling the device!
            cycle(app, fp, f1, f2, fs, dmm)
            
        end

        % Button pushed function: StopCyclingButton
        function StopCyclingButtonPushed(app, event)
            % toggle off the boolean that continues cycling
            app.contCycle = false;
            % hold(app.ScopePlot,'off')
        end

        % Button pushed function: UpdateValuesButton
        function UpdateValuesButtonPushed(app, event)
            % update the pulse parameters from the input fields
            app.crysAmp = app.CrysVoltageVEditField.Value;
            app.crysDur = app.CrysWidthsEditField.Value;
            app.amorAmp = app.AmorVoltageVEditField.Value;
            app.amorDur = app.AmorWidthusEditField.Value * 1E-6;
            app.primStart = app.PrimeStartVEditField.Value;
            app.primSteps = app.PrimeStepsEditField.Value;
            app.crysPause = app.CrysPauseEditField.Value;
            app.amorPause = app.AmorPauseEditField.Value;

            % update the text on the GUI
            app.CAVal.Text = sprintf('%.1f',app.crysAmp);
            app.CWVal.Text = sprintf('%.2f',app.crysDur);
            app.AAVal.Text = sprintf('%.1f',app.amorAmp);
            app.AWVal.Text = sprintf('%.1f',app.amorDur * 1E6);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.pauseCycle = true; % turn on the pause logical
            app.PauseLabel.Text = 'PAUSED'; % display 'PAUSED' on the GUI
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
             app.pauseCycle = false; % turn off the pause logical
            app.PauseLabel.Text = ' '; % remove 'PAUSED' text from the GUI
        end

        % Button pushed function: StartCountdownButton
        function StartCountdownButtonPushed(app, event)
            app.countdown = app.CountdownEditField.Value; % update countdown timer
            app.CyclesremainingLabel.Text = sprintf('Cycles remaining: %d',app.countdown); % update GUI text
        end

        % Value changed function: AutomaticallyUpdateCheckBox
        function AutomaticallyUpdateCheckBoxValueChanged(app, event)
            app.autoUp = app.AutomaticallyUpdateCheckBox.Value; % update auto-update logical
            
        end

        % Callback function
        function ChangeDirectoryButtonPushed(app, event)
            app.saveDir = uigetdir('','Pick a location to store log files'); % obsolete
            app.DefaultLabel.Text = app.saveDir; % obsolete
        end

        % Value changed function: StorePulseDataCheckBox
        function StorePulseDataCheckBoxValueChanged(app, event)
           app.recPulse = app.StorePulseDataCheckBox.Value; % update record pulse logical
           if app.recPulse
               % if pulse recording is desired, the oscope must also be
               % read so this logical is also toggled to on
               app.ReadOscopeCheckBox.Value = true;
           end
        end

        % Button pushed function: DisplaySampleImageButton
        function DisplaySampleImageButtonPushed(app, event)
            % This function just sends a pulse to the second function
            % generator to acquire an image and then pulls it from the save
            % directory. However, since this is to be done while the device
            % isn't cycling, the instrument must be initialized first.
            
            % initialize the second function generator
            fagi_02 = visa('NI', 'USB0::0x0957::0x0407::SG44000855::0::INSTR'); % function generator for triggering FLIR
            set (fagi_02,'OutputBufferSize',100000);
            fopen(fagi_02);

            % configure the pulse parameters
            fprintf(fagi_02,'FREQ 200e-3'); % Set the frequency for image capture
            fprintf(fagi_02,'FUNCTION PULSE'); % Put the tool in pulse mode
            fprintf(fagi_02,'FUNCtion:PULSe:WIDTh 1'); % Set the pulse Width for image capture
            fprintf(fagi_02,'BURST:STATE ON'); %  Make it burst so that we send only a burst of pulses at a time
            fprintf(fagi_02,'BURST:MODE TRIGGERED'); % Make it triggered mode
            fprintf(fagi_02,'BURST:NCYCLES 1'); % Send only 1 pulse
            fprintf(fagi_02,'TRIGGER:SOURCE BUS'); % Use the USB bus as trigger
            fprintf(fagi_02,'VOLTAGE:HIGH 5'); %
            fprintf(fagi_02,'VOLTAGE:LOW 0'); % Set it to 0 so that the transistor is turned off
            fprintf(fagi_02,'OUTPUT:LOAD 50'); % Set output load to 50 ohms - maybe not needed but seems to work
            fprintf(fagi_02,'OUTPUT ON'); % keep the output of FG 2 constantly ON
            
            % trigger image capture
            fprintf(fagi_02,'TRIG:SOUR BUS;*TRG');

            pause(15) % wait for file to save

            % retrieve newly captured image and plot it on the 'Camera
            % Setup' tab on the GUI
            filename = fullfile(app.camDir,app.ImageFileNameEditField.Value);

            v = FlirMovieReader(filename);

            img = step(v);
            pad = img(app.YEditField.Value:app.YEditField_2.Value,app.XEditField.Value:app.XEditField_2.Value);


            imagesc(img,'Parent',app.CamImg)
            imagesc(pad,'Parent',app.PadImg)

            fclose(fagi_02); % close the second fgen visa link
            
        end

        % Button pushed function: SpecifyFLIRDirectoryButton
        function SpecifyFLIRDirectoryButtonPushed(app, event)
            app.camDir = uigetdir('','Specify where the camera saves files'); % defines the save location for the images and log files
            app.saveDir = app.camDir;
        end

        % Value changed function: DeltaGoalEditField
        function DeltaGoalEditFieldValueChanged(app, event)
            app.delGoal = app.DeltaGoalEditField.Value; % updates the target delta R
            
        end

        % Value changed function: IncludePrimingCheckBox
        function IncludePrimingCheckBoxValueChanged(app, event)
            app.IncldPriming = app.IncludePrimingCheckBox.Value; % enables/disables priming

        end

        % Value changed function: DatamodeSwitch
        function DatamodeSwitchValueChanged(app, event)
            value = app.DatamodeSwitch.Value; % switches between transmission and reflection measurements
            if contains(value,'Tr')
                app.expMode = true; % expMode true is transmission mode
            else
                app.expMode = false; % expMode false is reflection mode
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {595, 595, 595};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {595, 595};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {285, '1x', 229};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ExperimentControlTab = uitab(app.TabGroup);
            app.ExperimentControlTab.Title = 'Experiment Control';
            app.InitialInputsLabel = uilabel(app.ExperimentControlTab);
            app.InitialInputsLabel.Text = 'Initial Inputs';
            app.CrysVoltageVEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.CrysVoltageVEditFieldLabel.Text = 'Crys. Voltage (V)';
            app.CrysVoltageVEditField = uieditfield(app.ExperimentControlTab, 'numeric');
            app.CrysVoltageVEditField.Limits = [0 Inf];
            app.CrysVoltageVEditField.Value = 10;
            app.CrysWidthsEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.CrysWidthsEditFieldLabel.Text = 'Crys.Width (s)';
            app.CrysWidthsEditField = uieditfield(app.ExperimentControlTab, 'numeric');
            app.CrysWidthsEditField.Limits = [0 Inf];
            app.CrysWidthsEditField.Value = 1;
            app.CrysPauseEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.CrysPauseEditFieldLabel.Text = 'Crys. Pause';
            app.CrysPauseEditField = uieditfield(app.ExperimentControlTab, 'numeric');
            app.CrysPauseEditField.Limits = [0 Inf];
            app.CrysPauseEditField.Value = 5;
            app.AmorVoltageVEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.AmorVoltageVEditFieldLabel.Text = 'Amor. Voltage (V)';
            app.AmorVoltageVEditField = uieditfield(app.ExperimentControlTab, 'numeric');
            app.AmorVoltageVEditField.Limits = [0 Inf];
            app.AmorVoltageVEditField.Value = 10;
            app.AmorWidthusEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.AmorWidthusEditFieldLabel.Text = 'Amor. Width (us)';
            app.AmorWidthusEditField = uieditfield(app.ExperimentControlTab, 'numeric');
            app.AmorWidthusEditField.Limits = [0 Inf];
            app.AmorWidthusEditField.Value = 10;
            app.AmorPauseEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.AmorPauseEditFieldLabel.Text = 'Amor. Pause';
            app.AmorPauseEditField = uieditfield(app.ExperimentControlTab, 'numeric');
            app.AmorPauseEditField.Limits = [0 Inf];
            app.AmorPauseEditField.Value = 5;
            app.IncludePrimingCheckBox = uicheckbox(app.ExperimentControlTab);
            app.IncludePrimingCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludePrimingCheckBoxValueChanged, true);
            app.IncludePrimingCheckBox.Text = 'Include Priming';
            app.PrimeStartVEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.PrimeStartVEditFieldLabel.Text = 'Prime Start (V)';
            app.PrimeStartVEditField = uieditfield(app.ExperimentControlTab, 'numeric');
            app.PrimeStartVEditField.Limits = [0 Inf];
            app.PrimeStartVEditField.Value = 1;
            app.PrimeStepsEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.PrimeStepsEditFieldLabel.Text = 'Prime Steps';
            app.PrimeStepsEditField = uieditfield(app.ExperimentControlTab, 'numeric');
            app.PrimeStepsEditField.Limits = [1 20];
            app.PrimeStepsEditField.Value = 5;
            app.SpecimenEditFieldLabel = uilabel(app.ExperimentControlTab);
            app.SpecimenEditFieldLabel.Text = 'Specimen';
            app.SpecimenEditField = uieditfield(app.ExperimentControlTab, 'text');
            app.SpecimenEditField.Placeholder = 'BatchX_RoundX_PadXXXX_CycleN';
            app.StartCyclingButton = uibutton(app.ExperimentControlTab, 'push');
            app.StartCyclingButton.ButtonPushedFcn = createCallbackFcn(app, @StartCyclingButtonPushed, true);
            app.StartCyclingButton.Text = 'Start Cycling';
            app.PauseButton = uibutton(app.ExperimentControlTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.UpdateValuesButton = uibutton(app.ExperimentControlTab, 'push');
            app.UpdateValuesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateValuesButtonPushed, true);
            app.UpdateValuesButton.Text = 'Update Values';
            app.ResumeButton = uibutton(app.ExperimentControlTab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.StopCyclingButton = uibutton(app.ExperimentControlTab, 'push');
            app.StopCyclingButton.ButtonPushedFcn = createCallbackFcn(app, @StopCyclingButtonPushed, true);
            app.StopCyclingButton.Text = 'Stop Cycling';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.CameraInformationLabel = uilabel(app.SetupTab);
            app.CameraInformationLabel.Text = 'Camera Information';
            app.TopLeftCornerCoordinatesLabel = uilabel(app.SetupTab);
            app.TopLeftCornerCoordinatesLabel.Text = 'Top Left Corner Coordinates';
            app.XEditFieldLabel = uilabel(app.SetupTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.SetupTab, 'numeric');
            app.XEditField.Limits = [1 640];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.Value = 1;
            app.YEditFieldLabel = uilabel(app.SetupTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.SetupTab, 'numeric');
            app.YEditField.Limits = [1 512];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.Value = 1;
            app.BottomRightCornerCoordinatesLabel = uilabel(app.SetupTab);
            app.BottomRightCornerCoordinatesLabel.Text = 'Bottom Right Corner Coordinates';
            app.XEditField_2Label = uilabel(app.SetupTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.SetupTab, 'numeric');
            app.XEditField_2.Limits = [1 640];
            app.XEditField_2.RoundFractionalValues = 'on';
            app.XEditField_2.Value = 1;
            app.YEditField_2Label = uilabel(app.SetupTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.SetupTab, 'numeric');
            app.YEditField_2.Limits = [1 512];
            app.YEditField_2.RoundFractionalValues = 'on';
            app.YEditField_2.Value = 1;
            app.SpecifyFLIRDirectoryButton = uibutton(app.SetupTab, 'push');
            app.SpecifyFLIRDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifyFLIRDirectoryButtonPushed, true);
            app.SpecifyFLIRDirectoryButton.Text = 'Specify FLIR Directory';
            app.ImageFileNameEditFieldLabel = uilabel(app.SetupTab);
            app.ImageFileNameEditFieldLabel.Text = 'Image File Name';
            app.ImageFileNameEditField = uieditfield(app.SetupTab, 'text');
            app.ImageFileNameEditField.Placeholder = 'Pad_XXXX_Cycle';
            app.DisplaySampleImageButton = uibutton(app.SetupTab, 'push');
            app.DisplaySampleImageButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySampleImageButtonPushed, true);
            app.DisplaySampleImageButton.Text = 'Display Sample Image';
            app.SavelocationDefaultisapplocationLabel = uilabel(app.SetupTab);
            app.SavelocationDefaultisapplocationLabel.Text = 'Save location (Default is app location):';
            app.DefaultLabel = uilabel(app.SetupTab);
            app.DefaultLabel.Text = 'Default';
            app.DatamodeSwitchLabel = uilabel(app.SetupTab);
            app.DatamodeSwitchLabel.Text = 'Data mode';
            app.DatamodeSwitch = uiswitch(app.SetupTab, 'toggle');
            app.DatamodeSwitch.Items = {'Transmission', 'Reflection'};
            app.DatamodeSwitch.ValueChangedFcn = createCallbackFcn(app, @DatamodeSwitchValueChanged, true);
            app.DatamodeSwitch.Value = 'Transmission';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.BelowareexamplefieldnameinputsforreferenceLabel = uilabel(app.InfoTab);
            app.BelowareexamplefieldnameinputsforreferenceLabel.Text = {'Below are example fieldname inputs for reference:'; ''; 'In this GUI - '; ''; 'Specimen Name:'; '''Batch6_Round1_Pad4312_Cycle0'''; ''; 'Image File Name:'; '''Pad_4312_Cycle'''; ''; ''; 'In ResearchIR - '; ''; 'Name:'; '''Pad_4312_Cycle'''; '** This MUST match the image file name above'; ''; 'Count:'; '1'; '** This should be 1 greater than the cycle number in the specimen name'};
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.CenterPanel);
            app.InstrumentsTab = uitab(app.TabGroup2);
            app.InstrumentsTab.Title = 'Instruments';
            app.ScopePlot = uiaxes(app.InstrumentsTab);
            title(app.ScopePlot, 'Oscilloscope')
            xlabel(app.ScopePlot, 'Time')
            ylabel(app.ScopePlot, 'Voltage')
            zlabel(app.ScopePlot, 'Z')
            app.TrPlot = uiaxes(app.InstrumentsTab);
            title(app.TrPlot, 'Counts and Resistance')
            xlabel(app.TrPlot, 'Cycle')
            ylabel(app.TrPlot, 'Counts')
            zlabel(app.TrPlot, 'Z')
            app.StorePulseDataCheckBox = uicheckbox(app.InstrumentsTab);
            app.StorePulseDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @StorePulseDataCheckBoxValueChanged, true);
            app.StorePulseDataCheckBox.Text = 'Store Pulse Data';
            app.ReadOscopeCheckBox = uicheckbox(app.InstrumentsTab);
            app.ReadOscopeCheckBox.Text = 'Read Oscope';
            app.UpdatePlotCheckBox = uicheckbox(app.InstrumentsTab);
            app.UpdatePlotCheckBox.Text = 'Update Plot';
            app.AutonomousControlTab = uitab(app.TabGroup2);
            app.AutonomousControlTab.Title = 'Autonomous Control';
            app.FeedbackPlot = uiaxes(app.AutonomousControlTab);
            title(app.FeedbackPlot, 'Autonomous Voltage Control')
            xlabel(app.FeedbackPlot, 'Switching Event')
            ylabel(app.FeedbackPlot, 'Voltage [V]')
            zlabel(app.FeedbackPlot, 'Z')
            app.PadImgDelta = uiaxes(app.AutonomousControlTab);
            title(app.PadImgDelta, 'Pad Delta Image')
            xlabel(app.PadImgDelta, 'X')
            ylabel(app.PadImgDelta, 'Y')
            zlabel(app.PadImgDelta, 'Z')
            app.PadImgDelta.XLimitMethod = 'tight';
            app.PadImgDelta.YLimitMethod = 'tight';
            app.DeltaGoalEditFieldLabel = uilabel(app.AutonomousControlTab);
            app.DeltaGoalEditFieldLabel.Text = 'Delta Goal';
            app.DeltaGoalEditField = uieditfield(app.AutonomousControlTab, 'numeric');
            app.DeltaGoalEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaGoalEditFieldValueChanged, true);
            app.DetectDamageCheckBox = uicheckbox(app.AutonomousControlTab);
            app.DetectDamageCheckBox.Text = 'Detect Damage';
            app.DamageVoltageReductionEditFieldLabel = uilabel(app.AutonomousControlTab);
            app.DamageVoltageReductionEditFieldLabel.Text = 'Damage Voltage Reduction';
            app.DamageVoltageReductionEditField = uieditfield(app.AutonomousControlTab, 'numeric');
            app.CameraSetupTab = uitab(app.TabGroup2);
            app.CameraSetupTab.Title = 'Camera Setup';
            app.CamImg = uiaxes(app.CameraSetupTab);
            title(app.CamImg, 'Camera Image')
            xlabel(app.CamImg, 'X')
            ylabel(app.CamImg, 'Y')
            zlabel(app.CamImg, 'Z')
            app.CamImg.XLimitMethod = 'tight';
            app.CamImg.YLimitMethod = 'tight';
            app.CamImg.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.PadImg = uiaxes(app.CameraSetupTab);
            title(app.PadImg, 'Pad Image')
            xlabel(app.PadImg, 'X')
            ylabel(app.PadImg, 'Y')
            zlabel(app.PadImg, 'Z')
            app.PadImg.XLimitMethod = 'tight';
            app.PadImg.YLimitMethod = 'tight';
            app.RightPanel = uipanel(app.GridLayout);
            app.CurrentInputsLabel = uilabel(app.RightPanel);
            app.CurrentInputsLabel.Text = 'Current Inputs';
            app.AutomaticallyUpdateCheckBox = uicheckbox(app.RightPanel);
            app.AutomaticallyUpdateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticallyUpdateCheckBoxValueChanged, true);
            app.AutomaticallyUpdateCheckBox.Text = 'Automatically Update';
            app.CrysAmplitudeEditFieldLabel_2 = uilabel(app.RightPanel);
            app.CrysAmplitudeEditFieldLabel_2.Text = 'Crys. Amplitude (V)';
            app.CAVal = uilabel(app.RightPanel);
            app.CAVal.Text = '0';
            app.CrysWidthEditFieldLabel_2 = uilabel(app.RightPanel);
            app.CrysWidthEditFieldLabel_2.Text = 'Crys. Width (s)';
            app.CWVal = uilabel(app.RightPanel);
            app.CWVal.Text = '0';
            app.AmorAmplitudeEditFieldLabel_2 = uilabel(app.RightPanel);
            app.AmorAmplitudeEditFieldLabel_2.Text = 'Amor. Amplitude (V)';
            app.AAVal = uilabel(app.RightPanel);
            app.AAVal.Text = '0';
            app.AmorWidthusLabel = uilabel(app.RightPanel);
            app.AmorWidthusLabel.Text = 'Amor. Width (us)';
            app.AWVal = uilabel(app.RightPanel);
            app.AWVal.Text = '0';
            app.CyclesnumberLabel = uilabel(app.RightPanel);
            app.CyclesnumberLabel.Text = 'Cycles number';
            app.CycVal = uilabel(app.RightPanel);
            app.CycVal.Text = '0';
            app.CountdownEditFieldLabel = uilabel(app.RightPanel);
            app.CountdownEditFieldLabel.Text = 'Countdown';
            app.CountdownEditField = uieditfield(app.RightPanel, 'numeric');
            app.StartCountdownButton = uibutton(app.RightPanel, 'push');
            app.StartCountdownButton.ButtonPushedFcn = createCallbackFcn(app, @StartCountdownButtonPushed, true);
            app.StartCountdownButton.Text = 'Start Countdown';
            app.CyclesremainingLabel = uilabel(app.RightPanel);
            app.CyclesremainingLabel.Text = 'Cycles remaining: inf';
            app.PauseLabel = uilabel(app.RightPanel);
            app.PauseLabel.Text = ' ';
            app.BatLabel = uilabel(app.RightPanel);
            app.BatLabel.Text = ' ';
            app.RndLabel = uilabel(app.RightPanel);
            app.RndLabel.Text = ' ';
            app.PadLabel = uilabel(app.RightPanel);
            app.PadLabel.Text = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProwessGUI_02152024_commented

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end